classdef Stimulator_Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Switch                         matlab.ui.control.Switch
        RewardPanel                    matlab.ui.container.Panel
        rewardPairValSwitch            matlab.ui.control.Switch
        RandomRewardButton             matlab.ui.control.StateButton
        PairedRewardButton             matlab.ui.control.StateButton
        sLickTimeoutSpinner            matlab.ui.control.Spinner
        sLickTimeoutSpinnerLabel       matlab.ui.control.Label
        GiveRewardButton               matlab.ui.control.Button
        RewardSizemsSpinner            matlab.ui.control.Spinner
        RewardSizemsLabel              matlab.ui.control.Label
        VacumSolenoidButton            matlab.ui.control.StateButton
        LiqSolButton                   matlab.ui.control.StateButton
        PauseButton                    matlab.ui.control.StateButton
        BarGrabLamp                    matlab.ui.control.Lamp
        BarGrabLabel                   matlab.ui.control.Label
        ExperimentPanel                matlab.ui.container.Panel
        PButton                        matlab.ui.control.StateButton
        ImgDepthEditField              matlab.ui.control.NumericEditField
        ImgDepthEditFieldLabel         matlab.ui.control.Label
        AudioonlyTrialsSpinner         matlab.ui.control.Spinner
        AudioonlyTrialsSpinnerLabel    matlab.ui.control.Label
        ImagingDataDirectoryEditField  matlab.ui.control.EditField
        ImagingDataDirectoryEditFieldLabel  matlab.ui.control.Label
        ITIsEditField_2                matlab.ui.control.NumericEditField
        MouseIDEditField               matlab.ui.control.EditField
        MouseIDEditFieldLabel          matlab.ui.control.Label
        ITIsEditField                  matlab.ui.control.NumericEditField
        ITIsLabel                      matlab.ui.control.Label
        TrialsEditField                matlab.ui.control.NumericEditField
        TrialsEditFieldLabel           matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        StatusLampLabel                matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        StimulusPanel                  matlab.ui.container.Panel
        ExpeimentEstimatesPanel        matlab.ui.container.Panel
        FrameRateEditField             matlab.ui.control.NumericEditField
        FrameRateLabel                 matlab.ui.control.Label
        FramesLabel                    matlab.ui.control.Label
        DurationLabel                  matlab.ui.control.Label
        HzSpinner                      matlab.ui.control.Spinner
        HzSpinnerLabel                 matlab.ui.control.Label
        VoltageSpinner                 matlab.ui.control.Spinner
        VoltageSpinnerLabel            matlab.ui.control.Label
        PreLoadStimuliButton           matlab.ui.control.Button
        ofstepsSpinner_2               matlab.ui.control.Spinner
        ofstepsSpinner_2Label          matlab.ui.control.Label
        MaxHzSpinner                   matlab.ui.control.Spinner
        MaxHzSpinnerLabel              matlab.ui.control.Label
        MinHzSpinner                   matlab.ui.control.Spinner
        MinHzSpinnerLabel              matlab.ui.control.Label
        ofstepsSpinner                 matlab.ui.control.Spinner
        ofstepsSpinnerLabel            matlab.ui.control.Label
        MaxVSpinner                    matlab.ui.control.Spinner
        MaxVSpinnerLabel               matlab.ui.control.Label
        MaxSSpinner                    matlab.ui.control.Spinner
        MaxSSpinnerLabel               matlab.ui.control.Label
        MinSSpinner                    matlab.ui.control.Spinner
        MinSSpinnerLabel               matlab.ui.control.Label
        SecondsSpinner                 matlab.ui.control.Spinner
        SecondsSpinnerLabel            matlab.ui.control.Label
        DurationSwitch                 matlab.ui.control.ToggleSwitch
        DurationSwitchLabel            matlab.ui.control.Label
        FrequencySwitch                matlab.ui.control.ToggleSwitch
        FrequencySwitchLabel           matlab.ui.control.Label
        MinVSpinner                    matlab.ui.control.Spinner
        MinVSpinnerLabel               matlab.ui.control.Label
        AmplitudeSwitch                matlab.ui.control.ToggleSwitch
        AmplitudeSwitchLabel           matlab.ui.control.Label
        MouseForepawStimulatorLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Local variable descriptions
        expRunning = 0;
        savedir = pwd;
        working_dir = NaN;
        mouseID = 0;
        sessionName = "Training";
        outData = struct();
        exp_start_time = NaN;
        exp_stop_time = NaN;
        exp_duration = NaN;
        currDate = NaN;
        stimOnFrameNum = NaN;
        pauseFlag = 0;
        CommunicationFlag = "2P";
        
        %Scan Image Parameters
        frameRate = NaN; %framerate of scanImage
        frameNumEstimate = NaN; %estimated total number of frames 
        ledFrameDelay = 5; %Delay 5 frames before stim start after led flash
        imagingDepth = NaN; %depth relative to cortical surface (from scanImage)
        
        % NI-DAQ Parameters
        frameDetection = []; %variable for listener channels
        TTL_Times = []; %variable to hold TTL recieved times
        TTL_recieved = 0; %boolean flag to indicate a TTL was recieved
        frameCtrDev = NaN;
        CH_state = 0;
        piezoDriver = [];
        piezoAudio = [];
        capSensPower = [];
        lickSensPower = [];
        capListener = [];
        handleGraspedRight = 0;
        graspTimesRight = [];
        graspTimesRightOff = [];
        spoutLicked = 0;
        lickTimes = [];
        fluidReward = NaN;
        fluidRewardSize = NaN;
        neuropixComm = NaN;
        
        lh = [];
        commChannels = [];
        led = [];
        
        % Stimulus Parameters
        FS = 10000;
        stim_dur = 1; %app.SecondsSpinner.Value;
        stim_durs = NaN; 
        stim_dur_min = NaN; %app.MinSSpinner.Value
        stim_dur_max = NaN; %app.MaxSSpinner.Value;
        all_durs = NaN; %stored in ms
        
        stim_amp = NaN; %app.VoltageSlider.Value;
        stim_amp_min = NaN; %app.MinVSpinner.Value;
        stim_amp_max = NaN; %app.MaxVSpinner.Value;
        stim_amp_steps = NaN; %number of amplitude steps
        stim_amps = NaN; %array of possible amplitude values
        all_amps = NaN;
        
        stim_freq = NaN; %app.HzSlider.Value; %constant frequency value
        stim_freq_min = NaN; %app.MinHzSpinner.Value; %min frequency value
        stim_freq_max = NaN; %app.MaxHzSpinner.Value; %max frequency value
        stim_freq_steps = NaN; %app.ofstepsSpinner_2.Value; %number of frequency steps
        stim_freqs = NaN;% array of possible frequency values
        all_freqs = NaN;
        
        num_trials = NaN;
        stim_space = NaN;
        iti_min = NaN; %inter-trial interval
        iti_max = NaN; %inter-trial interval
        iti_list = NaN;
        stim_times = NaN; %stim ON times
        stop_flag = 0; %off, proceed
        led_times = NaN;
        audioTrials = NaN; %percentage of trials with audio only stimuli.
        audioTrialsLog = NaN; %logical array to indicate which trails are audio only.
         
        %Output items
        frameTimes = NaN;
        idx_file = NaN;
        
        %Reward params
        rewardInit = 0;
        lickTimeout = 0;
        pairedReward = NaN;
        pairedRewardVal = 'Low';
        randomReward = NaN;
        
    end
    
    methods (Access = public)
        
        function TTL_detection(app, ~, event)
            
            %extracting data from NI
            chData = event.Data(:,2);
            chData2 = event.Data(:,1);
            
%             plot(chData2) 
%             hold on; 
%             plot(chData)
%             hold off;
            
            %determining changes in signals
            d_chData = diff(chData);
            d_chData2 = diff(chData2);
            
            %LICK LOGIC
            checkLick_up = find(d_chData2 > 2, 1);
            checkLick_dn = find(d_chData2 < -2, 1);
            if ~isempty(checkLick_up)
                if ~isempty(checkLick_dn)
                    if abs(checkLick_dn - checkLick_up) < 5
                        %not a true licks, just noise.
                    else
                        if app.spoutLicked == 0
                            app.lickTimes = cat(1,app.lickTimes,GetSecs);
                            app.spoutLicked = 1;
                            %disp("lick detected");
                        else
                        end
                    end
                else
                    if app.spoutLicked == 0
                        app.lickTimes = cat(1,app.lickTimes,GetSecs);
                        app.spoutLicked = 1;
                        %disp("lick detected");
                    else
                    end
                end
            else
                if isempty(checkLick_dn)
                    if abs(mean(chData2)) > 4
                        % most of the time its at -0.8v (baseline) deflects to +4.5v when active
                        % this is just noise, i.e. spout is mid-lick.
                    else
                        if app.spoutLicked == 1
                            app.spoutLicked = 0; 
                            %v disp("lick cleared")
                        else
                        end
                    end
                else
                end 
            end 
            
            
            %GRASP LOGIC
            checkHandleRight_up = find(d_chData > 2, 1);
            checkHandleRight_dn = find(d_chData < -2, 1);    
            if ~isempty(checkHandleRight_up)
                if ~isempty(checkHandleRight_dn)
                    if abs(checkHandleRight_dn - checkHandleRight_up) < 5
                        %not a true grasps, just noise.
                    else
                        if app.handleGraspedRight == 0
                            app.graspTimesRight = cat(1,app.graspTimesRight,GetSecs);
                            app.handleGraspedRight = 1;
                            app.BarGrabLamp.Color = [0,1,0];
                        else
                        end
                    end
                else
                    if app.handleGraspedRight == 0
                        app.graspTimesRight = cat(1,app.graspTimesRight,GetSecs);
                        app.handleGraspedRight = 1;
                        app.BarGrabLamp.Color = [0,1,0];
                    else
                    end
                end
            else
                if isempty(checkHandleRight_dn)
                    if abs(mean(chData)) > 4
                        % most of the time its at -0.8v (baseline) deflects to +4.5v when grabbed
                        % this is just noise, i.e. bar is still grasped.
                    else
                        if app.handleGraspedRight == 1
                            app.graspTimesRightOff = cat(1,app.graspTimesRightOff,GetSecs);
                            app.handleGraspedRight = 0;
                            app.BarGrabLamp.Color = [1,0,0];
                        else
                        end
                    end
                else
                end 
            end   
        end
    end
    
    methods (Access = private)
        
        function saveData(app,expEnd)
            % Combine Data
            app.outData.stimDurations = app.all_durs;
            app.outData.stimFrequencies = app.all_freqs;
            app.outData.stimAmplitudes = app.all_amps;
            app.outData.stimONTimes = app.stim_times;
            %app.outData.frameTimes = app.frameTimes;
            app.outData.ledTimes = app.led_times;
            app.outData.stimOnFrameNum = app.stimOnFrameNum;
            app.outData.expStartTime = app.exp_start_time;
            app.outData.expStopTime = app.exp_stop_time;
            app.outData.audioTrials = app.audioTrialsLog;
            app.outData.lickTimes = app.lickTimes;
            app.outData.graspTimesRight = app.graspTimesRight;
            app.outData.graspTimesRightOff = app.graspTimesRightOff;
            app.outData.imagingDepth = app.imagingDepth;
        
            % save the cell variable holding all the information on a trial by trial basis
            Data = app.outData;
            fn = strcat(app.working_dir, '\', app.mouseID, '\', app.currDate, '\', app.mouseID, '_', app.sessionName, '_', app.idx_file);
            save(fn, 'Data');
            
            if expEnd == 1
                myDate = datestr(datetime('today'),'yy/mm/dd');
                myDate(findstr(myDate,'/')) = '-';
                myDest = [app.ImagingDataDirectoryEditField.Value '\' num2str(app.MouseIDEditField.Value) '\' myDate '\'];
                
                [success, ~, msgID] = copyfile(strcat(fn,'.mat'),myDest, 'f');
                if success ~= 1
                    errordlg('Behavioral File Not Transfered','File Transfer Error','modal');
                    disp(msgID)
                else
                end
                
            else
            end
            
        end
        %-------------------------------------------------------------
        function betterPause(~,n)
            startTime = GetSecs;
            while (GetSecs - startTime) < n
                pause(0);
            end
        end
        %-------------------------------------------------------------
        
        function results = initializeReward(app)
            %set up channels to control solenoids for fluid delivery: Liquid(5) Vaccum(6)
            app.fluidReward = daq.createSession('ni');
            addDigitalChannel(app.fluidReward,'Dev1',['Port1/Line5','Port1/Line6'],'OutputOnly');
            outputSingleScan(app.fluidReward,[0 0]); %keep solenoids closed
            app.fluidRewardSize = app.RewardSizemsSpinner.Value;
            app.lickTimeout = app.sLickTimeoutSpinner.Value;
            app.rewardInit = 1;
            app.pairedReward = app.PairedRewardButton.Value;
            app.randomReward = app.RandomRewardButton.Value;
        end
        
        function results = deliverReward(app)
            % Give reward
            Twait = app.fluidRewardSize / 1000; %convert from ms to sec
            Tlick = app.lickTimeout; %wait for animal to lick (sec.)
            outputSingleScan(app.fluidReward,[1 0]); %open fluid solenoid
            
            % Fluid delivery pause (reward size)
            T = GetSecs;
            while GetSecs - T < Twait %fluid delivery pause (reward size)
                pause(0);
            end
            outputSingleScan(app.fluidReward,[0 0]); %close fluid solenoid
            
            % Post-reward pause (animal licks)
            T = GetSecs;
            while GetSecs - T < Tlick
                pause(0);
            end
            outputSingleScan(app.fluidReward,[0 1]); %open vacuum solenoid
            
            % Vacuum Open duration (clear reward)
            T = GetSecs;
            while GetSecs - T < 1
                pause(0);
            end
            outputSingleScan(app.fluidReward,[0 0]); %close vacuum solenoid
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %not used
        end

        % Callback function
        function BrowseButtonPushed(app, event)
            selpath = uigetdir;
            app.savedir = selpath;
        end

        % Callback function
        function DirectorytosaveoutputEditFieldValueChanged(app, event)
            %value = app.DirectorytosaveoutputEditField.Value;
            %User clicked in the directory input
            
        end

        % Button pushed function: PreLoadStimuliButton
        function PreLoadStimuliButtonPushed(app, event)
            % Create the piezo stimulus file
            % Get local copies of variables
            app.num_trials = app.TrialsEditField.Value;
            app.FS = 10000;
            app.stim_dur = app.SecondsSpinner.Value * 1000; %constant duration value
            app.stim_durs = NaN; %array of randomized variable duration values in ms
            app.stim_dur_min = app.MinSSpinner.Value * 1000; %min stim duration in ms
            app.stim_dur_max = app.MaxSSpinner.Value * 1000; %max stim duration in ms
            
            app.stim_amp = app.VoltageSpinner.Value; %constant amplitude value
            app.stim_amp_min = app.MinVSpinner.Value; %min amplitude value
            app.stim_amp_max = app.MaxVSpinner.Value; %max amplitude value
            app.stim_amp_steps = app.ofstepsSpinner.Value; %number of amplitude steps
            app.stim_amps = linspace(app.stim_amp_min,app.stim_amp_max,app.stim_amp_steps); %array of possible amplitude values
            
            app.stim_freq = app.HzSpinner.Value; %constant frequency value
            app.stim_freq_min = app.MinHzSpinner.Value; %min frequency value
            app.stim_freq_max = app.MaxHzSpinner.Value; %max frequency value
            app.stim_freq_steps = app.ofstepsSpinner_2.Value; %number of frequency steps
            app.stim_freqs = linspace(app.stim_freq_min, app.stim_freq_max, app.stim_freq_steps); % array of possible frequency values
            
            %Interleave some trials with audi only stimuli
            app.audioTrials = app.AudioonlyTrialsSpinner.Value;
            app.audioTrialsLog = zeros(app.num_trials,1);
            
            %set up outputs
            app.all_durs = zeros(app.num_trials,1);
            app.all_freqs = zeros(app.num_trials,1);
            app.all_amps = zeros(app.num_trials,1);
            app.stim_times = zeros(app.num_trials,1);
            app.led_times = zeros(app.num_trials,1);
            
            %Declare values
            app.iti_min = app.ITIsEditField.Value;
            app.iti_max = app.ITIsEditField_2.Value;            
            
            app.stim_space = cell(app.num_trials,1);
            if strcmp(app.DurationSwitch.Value,"Random") %random duration
                %app.stim_space = cell(app.num_trials,1);
                app.stim_durs = randi([app.stim_dur_min,app.stim_dur_max],app.num_trials,1);
                app.all_durs = app.stim_durs;
                for ii = 1 : app.num_trials
                    %trial_length = randi([app.stim_dur_min*1000,app.stim_dur_max*1000],1); %random stim duration per trial in ms
                    %t = 0:1/app.FS:app.durs(ii)/1000 - 1/app.FS;
                    app.stim_space{ii} = zeros(app.stim_durs(ii)/1000*app.FS,1);
                end
            else %constant duration
                %app.stim_space = cell(app.num_trials,1);
                %t = 0:1/app.FS:app.stim_dur - 1/app.FS;
                for ii = 1 : app.num_trials
                    app.stim_space{ii,1} = zeros(app.stim_dur/1000*app.FS,1);
                    app.all_durs(ii) = app.stim_dur;
                end
            end
            
            if strcmp(app.FrequencySwitch.Value,"Random") %random frequency
                repeats = ceil(app.num_trials / length(app.stim_freqs)); %number of repeats for each stim
                freqComp = repmat(app.stim_freqs,1,repeats);
                freqComp = freqComp(1:app.num_trials)'; %trim off excess trials
                if app.audioTrials > 0
                    audio_only = [ones(1,round(app.audioTrials*app.num_trials)),zeros(1,app.num_trials-round(app.audioTrials*app.num_trials))]';
                    stim_list = Shuffle([freqComp,audio_only],2);
                    app.audioTrialsLog = stim_list(:,2);
                else
                    stim_list = Shuffle(freqComp);
                end
                if strcmp(app.DurationSwitch.Value,"Random") %random duration
                    for ii = 1 : app.num_trials
                        %loc_freq = app.stim_freqs(randi(length(app.stim_freqs)));
                        loc_freq = stim_list(ii,1);
                        loc_t = 0:1/app.FS:app.stim_durs(ii)/1000 - 1/app.FS;
                        app.stim_space{ii,1} = sin(2*pi*loc_t*loc_freq);
                        app.all_freqs(ii,1) = loc_freq;
                    end
                else %constant duration
                    loc_t = 0:1/app.FS:app.stim_dur/1000 - 1/app.FS;
                    for ii = 1 : app.num_trials
                        %loc_freq = app.stim_freqs(randi(length(app.stim_freqs)));
                        loc_freq = stim_list(ii,1);
                        app.stim_space{ii,1} = sin(2*pi*loc_t*loc_freq);
                        app.all_freqs(ii,1) = loc_freq;
                    end
                end
                
            else %constant frequency
                if strcmp(app.DurationSwitch.Value,"Random") %random duration
                    for ii = 1 : app.num_trials
                        %loc_freq = app.stim_freqs(randi(length(app.stim_freqs)));
                        loc_t = 0:1/app.FS:app.stim_durs(ii)/1000 - 1/app.FS;
                        app.stim_space{ii,1} = sin(2*pi*loc_t*app.stim_freq);
                        app.all_freqs(ii,1) = app.stim_freq;
                    end
                else %constant duration
                    loc_t = 0:1/app.FS:app.stim_dur/1000 - 1/app.FS;
                    for ii = 1 : app.num_trials
                        app.stim_space{ii,1} = sin(2*pi*loc_t*app.stim_freq);
                        app.all_freqs(ii,1) = app.stim_freq;
                    end
                end
                
            end
            
            
            if strcmp(app.AmplitudeSwitch.Value,"Random") %random amplitude
                for ii = 1 : app.num_trials
                    loc_amp = app.stim_amps(randi(app.stim_amp_steps));
                    app.stim_space{ii,1} = app.stim_space{ii,1} .* loc_amp;
                    app.all_amps(ii) = loc_amp;
                end
            else %constant amplitude
                for ii = 1 : app.num_trials
                    app.stim_space{ii,1} = app.stim_space{ii,1} .* app.stim_amp;
                    app.all_amps(ii) = app.stim_amp;
                end
            end
            
            %Calculate all the random ITI's
            app.iti_list = randi([app.iti_min*1000,app.iti_max*1000],app.num_trials,1)/1000;
            
            %Calculate the total experiment duration
            app.exp_duration = sum(app.iti_list)+app.num_trials*1.5;
            
            %Display estimates
            app.frameRate = app.FrameRateEditField.Value;
            app.frameNumEstimate = round(app.exp_duration * app.frameRate);
            app.FramesLabel.Text = strcat("Frames: ",num2str(app.frameNumEstimate));
            app.DurationLabel.Text = strcat("Duration: ",num2str(app.exp_duration)," (s)");
            
            disp("Stimulus file loaded successfully...")
%             t_show = table();
%             t_show.NumTrials = [1:app.num_trials];
%             t_show.stimDurations = app.all_durs';
%             t_show.Frequencies = app.all_freqs';
%             t_show.Amplitudes = app.all_amps';
%             t_show.AudioTrials = app.audioTrialsLog';
%             t_show
            app.all_freqs
            app.audioTrialsLog
            
            app.PreLoadStimuliButton.BackgroundColor = [0, 1, 0];
        end

        % Value changed function: DurationSwitch
        function DurationSwitchValueChanged(app, event)
            %value = app.DurationSwitch.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: AmplitudeSwitch
        function AmplitudeSwitchValueChanged(app, event)
            %value = app.AmplitudeSwitch.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: FrequencySwitch
        function FrequencySwitchValueChanged(app, event)
            %value = app.FrequencySwitch.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: TrialsEditField
        function TrialsEditFieldValueChanged(app, event)
            %value = app.TrialsEditField.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Callback function
        function VoltageSliderValueChanged(app, event)
            %REPLACE THIS WITH A NUM INPUT
            %value = app.VoltageSlider.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Callback function
        function HzSliderValueChanged(app, event)
            %REPLACE THIS WITH NUM INPUT
            %value = app.HzSlider.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: ofstepsSpinner
        function ofstepsSpinnerValueChanged(app, event)
            %value = app.ofstepsSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: ofstepsSpinner_2
        function ofstepsSpinner_2ValueChanged(app, event)
            %value = app.ofstepsSpinner_2.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %check if data has been pre-loaded,
            %if not, then load data.
            % TODO
            
            app.expRunning = 1;
            
            %Creating output file
            %Output the saved data (out_times and out_events)
            app.working_dir = pwd; %current directory pre-save
            app.outData = [];
            app.TTL_recieved = 0; %reset frame counter
            app.frameRate = app.FrameRateEditField.Value;
            app.frameNumEstimate = app.exp_duration * app.frameRate;
            app.frameTimes = zeros(round(app.frameNumEstimate*1.1),1); %estimated number of frames +10% padding
            app.CommunicationFlag = app.Switch.Value;
            app.imagingDepth = app.ImgDepthEditField.Value;
            

            disp('Identifying directory to save mouse data...');
            app.savedir = 'C:\Users\ramirezlab\Desktop';
            cd(app.savedir);
            
            %-----------------
            %see if mouse directory exists
            app.mouseID = num2str(app.MouseIDEditField.Value);
            if isfolder([app.working_dir '\' app.mouseID]) ~= 1
                mkdir([app.working_dir '\' app.mouseID])
            else
            end
            
            app.currDate = datestr(date,2);
            app.currDate(strfind(app.currDate,'/')) = '_';
            
            if isfolder([app.working_dir '\' app.mouseID '\' app.currDate]) ~= 1
                mkdir([app.working_dir '\' app.mouseID '\' app.currDate]);
            else
            end
            
            curDirFile = dir([app.working_dir '\' app.mouseID '\' app.currDate]);
            curDirFile = {curDirFile.name};
            app.idx_file = 0;
            for ff = 1 : length(curDirFile)
                tempFile = curDirFile{ff};
                if contains(tempFile,[app.mouseID '_' app.sessionName])
                    app.idx_file = app.idx_file + 1;
                else
                end
            end
            
            if app.idx_file < 10
                app.idx_file = ['00' num2str(app.idx_file)];
            elseif app.idx_file >= 10 && app.idx_file < 100
                app.idx_file = ['0' num2str(app.idx_file)];
            else
                app.idx_file = num2str(app.idx_file);
            end
            
            expEnd = 0;
            saveData(app,expEnd); %quick save
            
            %start
            app.iti_min = app.ITIsEditField.Value;
            app.iti_max = app.ITIsEditField_2.Value;
            app.stop_flag = 0;
            app.stim_times = zeros(app.num_trials,1);
            
            %set up piezo channels for tactile stim
            app.piezoDriver = daq.createSession('ni');
            addAnalogOutputChannel(app.piezoDriver, 'Dev1', [0 1], 'Voltage');
            app.piezoDriver.Rate = app.FS; %sampling rate 
            
            %set up power channel for handle capacitive sensor
            app.capSensPower = daq.createSession('ni');
            addDigitalChannel(app.capSensPower,'dev1','Port0/Line1', 'OutputOnly');
            outputSingleScan(app.capSensPower,1); %turn on capacitive sensor
            
            %set up power channel for lick capacitive sensor
            app.lickSensPower = daq.createSession('ni');
            addDigitalChannel(app.lickSensPower,'dev1','Port0/Line2', 'OutputOnly');
            outputSingleScan(app.lickSensPower,1); %turn on capacitive sensor
            
            %set up listener channels
            app.capListener = daq.createSession('ni');
            addAnalogInputChannel(app.capListener,'Dev2',[0 2], 'Voltage'); 
            app.capListener.Rate = 10000;
            app.capListener.IsContinuous = true;
            app.capListener.IsNotifyWhenDataAvailableExceedsAuto = true;
            app.capListener.NotifyWhenDataAvailableExceeds = 1000;
            
            %set up channels to control solenoids for fluid delivery: Liquid(5) Vaccum(6)
            app.fluidReward = daq.createSession('ni');
            addDigitalChannel(app.fluidReward,'Dev1',['Port1/Line5','Port1/Line6'],'OutputOnly');
            outputSingleScan(app.fluidReward,[0 0]); %keep solenoids closed
            
            
            if strcmp(app.CommunicationFlag,"2P")
                %set up communication channels
                app.commChannels = arduino('COM4','Uno');
                configurePin(app.commChannels,'D2','DigitalOutput'); %Comm PF1 "Start"
                configurePin(app.commChannels,'D3','DigitalOutput'); %Comm PF2 "Stop"
                configurePin(app.commChannels,'D5','DigitalOutput'); %Signal LED (3 flash = exp start, 1 flash = stim_on)
                writeDigitalPin(app.commChannels,'D2',0);
                writeDigitalPin(app.commChannels,'D3',0);
                writeDigitalPin(app.commChannels,'D5',0);
            end

            if app.CommunicationFlag == "2P"
                app.frameCtrDev = daq("ni");
                ch = addinput(app.frameCtrDev,"Dev1", "ctr0", "EdgeCount");
                app.frameCtrDev.Rate = 10000;
                app.frameCtrDev.resetcounters;
                frameNum = 0;
                app.stimOnFrameNum = zeros(app.num_trials,1);
            elseif app.CommunicationFlag == "Ephy"
                app.neuropixComm = daq.createSession('ni');
                addDigitalChannel(app.neuropixComm,'dev1','Port2/Line0', 'OutputOnly');
                outputSingleScan(app.neuropixComm,0); %turn off comm channel
            end
            
            
            %start listener
            if strcmp(app.CommunicationFlag,"2P")
                disp('Starting listener');
                app.lh = addlistener(app.capListener,'DataAvailable', @(src,event) TTL_detection(app, src, event));
                %pause(0.1)
                startTime = GetSecs;
                while (GetSecs - startTime) < 0.1
                    pause(0);
                end
                disp('Starting collection of data in the background');
                startBackground(app.capListener);
            end
            
            if app.pairedReward == 1
                num_half = floor(app.stim_freq_steps/2);
                if strcmp(app.pairedRewardVal,'Low')
                    rewarded_freqs = app.stim_freqs(1:num_half-1);
                    disp(strcat("Rewarding Low Freqs: ",num2str(rewarded_freqs)));
                elseif strcmp(app.pairedRewardVal,'High')
                    rewarded_freqs = app.stim_freqs(end-num_half+2:end);
                    disp(strcat("Rewarding High Freqs: ",num2str(rewarded_freqs)));
                end
            end
            
            disp("Starting Experiment.")
            if strcmp(app.CommunicationFlag,"2P")
                writeDigitalPin(app.commChannels,'D2',1); %Pulse Scan Image "START
            end
            app.exp_start_time = GetSecs;
            startTime = GetSecs;
            while (GetSecs - startTime) < 0.01
                pause(0);
            end
            if strcmp(app.CommunicationFlag,"2P")
                writeDigitalPin(app.commChannels,'D2',0); %clear comm channel
            end
            
            if strcmp(app.CommunicationFlag,"2P")
                %Flash LEDs to indicate experiment start
                app.led_times(1,1) = GetSecs; %record time
                for n = 1 : 3 %repeats
                    writeDigitalPin(app.commChannels,'D5',1); %FLASH LED
                    %100ms pause
                    startTime = GetSecs;
                    while (GetSecs - startTime) < 0.1
                        pause(0);
                    end
                    writeDigitalPin(app.commChannels,'D5',0); %LED OFF
                    %100ms pause
                    startTime = GetSecs;
                    while (GetSecs - startTime) < 0.1
                        pause(0);
                    end
                end
            end

            for n=1:app.num_trials
                
                % Check for stop flag
                if app.stop_flag == 1
                    disp("Experiment Stopped!");
                    outputSingleScan(app.capSensPower,0); %turn off capacitive sensor
                    outputSingleScan(app.lickSensPower,0); %turn off capacitive sensor
                    break;
                elseif app.pauseFlag == 1
                    disp("Experiment paused.")
                    while app.pauseFlag == 1
                        pause(0)
                    end
                end
                
                trialSaveFlag = 0;
                
                % Display trial parameters
                disp("-------------------------------------------");
                if app.audioTrialsLog(n) == 1
                    disp(strcat("TRIAL ",num2str(n)," AUDIO-ONLY"));
                else
                    disp(strcat("TRIAL ",num2str(n)));
                end
                disp(strcat("Dur = ",num2str(app.all_durs(n)),"ms. Freq = ",num2str(app.all_freqs(n)),"hz. Amp = ",num2str(app.all_amps(n)),"v."));
                
                %Prepare for stim delivery
                if app.audioTrialsLog(n) == 1
                    app.piezoDriver.queueOutputData([(app.stim_space{n,1}'.*0),app.stim_space{n,1}']); %que audio stim
                else
                    app.piezoDriver.queueOutputData([app.stim_space{n,1}',(app.stim_space{n,1}'.*0)]); %que tactile stim
                end
                
%                 if app.handleGraspedRight == 0
%                     disp('Waiting for animal to grasp...');
%                     while app.handleGraspedRight == 0
%                         pause(0);
%                     end
%                 end
                
                if strcmp(app.CommunicationFlag,"2P")
                    %flash LED
                    writeDigitalPin(app.commChannels,'D5',1);
                    app.led_times(n,1) = GetSecs;
                    timePreITI = GetSecs;
                    while GetSecs < timePreITI + 0.01
                        pause(0);
                    end
                    writeDigitalPin(app.commChannels,'D5',0);
                end
                
                %delay stim on
                delayTime = app.ledFrameDelay / app.frameRate;
                pause(delayTime);
                
                % Deliver Stim
                disp("Delivering Stim...");
                % read counter channel to determine frame number
                if strcmp(app.CommunicationFlag,"2P")
                    tmp = read(app.frameCtrDev);
                    app.stimOnFrameNum(n) = tmp.Dev1_ctr0;
                else
                    outputSingleScan(app.neuropixComm,1); %turn on comm channel (STIM ON)
                end  
                
                app.piezoDriver.startBackground(); %deliver stim
                app.stim_times(n,1) = GetSecs;
                
                if ~strcmp(app.CommunicationFlag,"2P")
                    while GetSecs - app.stim_times(n,1) < 0.1
                        pause(0); %100 ms pause loop
                    end
                    outputSingleScan(app.neuropixComm,0); %turn off comm channel
                end
                
                %Present Reward (if a non-audio trial)
                if app.pairedReward == 1 && app.audioTrialsLog(n) == 0
                    %check for grasping
%                     if app.handleGraspedRight == 1
                        if strcmp(app.pairedRewardVal,'Low')
                            %Give reward on low freq trials
                            if any(app.all_freqs(n) == rewarded_freqs)
                                disp('Low Freq Trial Reward.');
                                app.deliverReward();
                            end
                        elseif strcmp(app.pairedRewardVal,'High')
                            %Give reward on high freq trials
                            if any(app.all_freqs(n) == rewarded_freqs)
                                disp('High Freq Trial Reward.');
                                app.deliverReward();
                            end
                        else
                            disp("ERROR: app.pairedRewardVal not recognized!");
                        end
                        %                     end
                elseif app.randomReward == 1 && app.audioTrialsLog(n) == 0
                    if randi([1 3],1) == 3 % 1/3 of trials
                        disp(strcat("[IMax]=",num2str((1000*app.iti_list(n))-100)));
                        rewardTime = randi([100 ((1000*app.iti_list(n))-100)],1);
                    else
                        rewardTime = NaN;
                    end 
                end
                randomRewardGiven = 0;
                rewardTime = NaN;
                %Pause for ITI
                timePreITI = GetSecs;
                disp(strcat("Pause ITI(s): ",num2str(app.iti_list(n))));
                ephysDelay = 1;
                while GetSecs < timePreITI + app.iti_list(n)
                    pause(0);
                    if ~isnan(rewardTime)
                        if GetSecs > timePreITI + rewardTime/1000 && randomRewardGiven == 0
                            %Give random reward
                            disp(strcat("Random Reward @ ",num2str(rewardTime)));
                            app.deliverReward();
                            randomRewardGiven = 1;
                        end
                    end
                    if app.CommunicationFlag == "Ephy" & ephysDelay == 1
                        outputSingleScan(app.neuropixComm,1); %turn off comm channel
                        locTime = GetSecs();
                        endTime = locTime + floor(app.all_durs(n)/1000);
                        while GetSecs < endTime
                            pause(0);
                        end
                        outputSingleScan(app.neuropixComm,0); %turn off comm channel
                        ephysDelay = 0;
                    end
                    if trialSaveFlag == 0
                        saveData(app,expEnd); %QUICK SAVE
                        trialSaveFlag = 1;
                    end
                end
                    
            end
            
            disp("Experiment Finished.");
            app.expRunning = 0;
            outputSingleScan(app.capSensPower,0); %turn off capacitive sensor
            disp("===========================================");
            if strcmp(app.CommunicationFlag,"2P")
                writeDigitalPin(app.commChannels,'D3',1);  %pulse scanImage "STOP"
            end
            app.exp_stop_time = GetSecs;
            if strcmp(app.CommunicationFlag,"2P")
                %100ms pause
                startTime2 = GetSecs;
                while (GetSecs - startTime2) < 0.01
                    pause(0);
                end
                writeDigitalPin(app.commChannels,'D3',0); %clear comm channel
            end
            
            expEnd = 1;
            saveData(app,expEnd); %quick save
            
            app.commChannels = [];
            
        end

        % Value changed function: ITIsEditField
        function ITIsEditFieldValueChanged(app, event)
            value = app.ITIsEditField.Value;
            app.iti_min = value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop_flag = 1;
            app.expRunning = 0;
            app.commChannels = [];
            disp("stop cmd recieved");
        end

        % Value changed function: VoltageSpinner
        function VoltageSpinnerValueChanged(app, event)
            value = app.VoltageSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: MinSSpinner
        function MinSSpinnerValueChanged(app, event)
            value = app.MinSSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: MaxSSpinner
        function MaxSSpinnerValueChanged(app, event)
            value = app.MaxSSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: HzSpinner
        function HzSpinnerValueChanged(app, event)
            value = app.HzSpinner.Value;
            app.PreLoadStimuliButton.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: MouseIDEditField
        function MouseIDEditFieldValueChanged(app, event)
            value = app.MouseIDEditField.Value;
            
        end

        % Value changed function: ITIsEditField_2
        function ITIsEditField_2ValueChanged(app, event)
            value = app.ITIsEditField_2.Value;
            app.iti_max = value;
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            value = app.FrameRateEditField.Value;
            app.frameRate = value;
        end

        % Value changed function: AudioonlyTrialsSpinner
        function AudioonlyTrialsSpinnerValueChanged(app, event)
            app.audioTrials = app.AudioonlyTrialsSpinner.Value;
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if value == 1
                app.pauseFlag = 1;
            else
                app.pauseFlag = 0;
            end
        end

        % Value changed function: LiqSolButton
        function LiqSolButtonValueChanged(app, event)
            if app.rewardInit == 0
                app.initializeReward();
            end
            value = app.LiqSolButton.Value;
            if value == 1
                outputSingleScan(app.fluidReward,[1 0]); %open fluid solenoid
            else
                outputSingleScan(app.fluidReward,[0 0]); %close both solenoids
            end
        end

        % Value changed function: VacumSolenoidButton
        function VacumSolenoidButtonValueChanged(app, event)
            if app.rewardInit == 0
                app.initializeReward();
            end
            value = app.VacumSolenoidButton.Value;
            if value == 1
                outputSingleScan(app.fluidReward,[0 1]); %open vacuum solenoid
            else
                outputSingleScan(app.fluidReward,[0 0]); %close both solenoids
            end
        end

        % Value changed function: RewardSizemsSpinner
        function RewardSizemsSpinnerValueChanged(app, event)
            app.fluidRewardSize = app.RewardSizemsSpinner.Value; %update reward size
        end

        % Button pushed function: GiveRewardButton
        function GiveRewardButtonPushed(app, event)
            if app.rewardInit == 0
                app.initializeReward();
            end
            
            
            % Give reward
            Twait = app.fluidRewardSize / 1000; %convert from ms to sec
            Tlick = app.lickTimeout; %wait for animal to lick (sec.)
            outputSingleScan(app.fluidReward,[1 0]); %open fluid solenoid
            
            % Fluid delivery pause (reward size)
            T = GetSecs;
            while GetSecs - T < Twait %fluid delivery pause (reward size)
                pause(0);
            end
            outputSingleScan(app.fluidReward,[0 0]); %close fluid solenoid
            
            % Post-reward pause (animal licks)
            T = GetSecs;
            while GetSecs - T < Tlick
                pause(0);
            end
            
            outputSingleScan(app.fluidReward,[0 1]); %open vacuum solenoid
            
            % Vacuum Open duration (clear reward)
            T = GetSecs;
            while GetSecs - T < 0.5
                pause(0);
            end
            outputSingleScan(app.fluidReward,[0 0]); %close vacuum solenoid
        end

        % Value changed function: sLickTimeoutSpinner
        function sLickTimeoutSpinnerValueChanged(app, event)
            app.lickTimeout = app.sLickTimeoutSpinner.Value;
        end

        % Value changed function: PairedRewardButton
        function PairedRewardButtonValueChanged(app, event)
            app.pairedReward = app.PairedRewardButton.Value;
            app.sessionName = "Paired-Reward";
        end

        % Value changed function: RandomRewardButton
        function RandomRewardButtonValueChanged(app, event)
            app.randomReward = app.RandomRewardButton.Value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.CommunicationFlag = value;
            disp(strcat(value," mode active."));
        end

        % Value changed function: rewardPairValSwitch
        function rewardPairValSwitchValueChanged(app, event)
            app.pairedRewardVal= app.rewardPairValSwitch.Value;
        end

        % Value changed function: ImgDepthEditField
        function ImgDepthEditFieldValueChanged(app, event)
            value = app.ImgDepthEditField.Value;
            app.imagingDepth = value;
        end

        % Callback function
        function QButtonPushed(app, event)
            % [2P] button pushed, Ca-Imaging_000
            app.sessionName = "Ca-Imaging";
        end

        % Value changed function: PButton
        function PButtonValueChanged(app, event)
            value = app.PButton.Value;
            % [2P] button pushed, Ca-Imaging_000
            if app.expRunning == 1
                disp("ERROR: Can't change Exp_Type while running!");
                return
            else
                if value == 1
                    app.sessionName = "Ca-Imaging";
                else
                    app.sessionName = "Training";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MouseForepawStimulatorLabel = uilabel(app.UIFigure);
            app.MouseForepawStimulatorLabel.Text = {'Mouse Forepaw Stimulator'; ''};
            app.StimulusPanel = uipanel(app.UIFigure);
            app.StimulusPanel.Title = 'Stimulus';
            app.AmplitudeSwitchLabel = uilabel(app.StimulusPanel);
            app.AmplitudeSwitchLabel.Text = 'Amplitude';
            app.AmplitudeSwitch = uiswitch(app.StimulusPanel, 'toggle');
            app.AmplitudeSwitch.Items = {'Random', 'Uniform'};
            app.AmplitudeSwitch.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSwitchValueChanged, true);
            app.AmplitudeSwitch.Value = 'Uniform';
            app.MinVSpinnerLabel = uilabel(app.StimulusPanel);
            app.MinVSpinnerLabel.Text = 'Min. (V)';
            app.MinVSpinner = uispinner(app.StimulusPanel);
            app.MinVSpinner.Limits = [0 Inf];
            app.MinVSpinner.Value = 0.1;
            app.FrequencySwitchLabel = uilabel(app.StimulusPanel);
            app.FrequencySwitchLabel.Text = 'Frequency';
            app.FrequencySwitch = uiswitch(app.StimulusPanel, 'toggle');
            app.FrequencySwitch.Items = {'Random', 'Uniform'};
            app.FrequencySwitch.ValueChangedFcn = createCallbackFcn(app, @FrequencySwitchValueChanged, true);
            app.FrequencySwitch.Value = 'Random';
            app.DurationSwitchLabel = uilabel(app.StimulusPanel);
            app.DurationSwitchLabel.Text = 'Duration';
            app.DurationSwitch = uiswitch(app.StimulusPanel, 'toggle');
            app.DurationSwitch.Items = {'Random', 'Uniform'};
            app.DurationSwitch.ValueChangedFcn = createCallbackFcn(app, @DurationSwitchValueChanged, true);
            app.DurationSwitch.Value = 'Uniform';
            app.SecondsSpinnerLabel = uilabel(app.StimulusPanel);
            app.SecondsSpinnerLabel.Text = 'Seconds';
            app.SecondsSpinner = uispinner(app.StimulusPanel);
            app.SecondsSpinner.Step = 0.25;
            app.SecondsSpinner.Limits = [0.001 Inf];
            app.SecondsSpinner.Value = 0.25;
            app.MinSSpinnerLabel = uilabel(app.StimulusPanel);
            app.MinSSpinnerLabel.Text = 'Min. (S)';
            app.MinSSpinner = uispinner(app.StimulusPanel);
            app.MinSSpinner.Limits = [0 Inf];
            app.MinSSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSSpinnerValueChanged, true);
            app.MinSSpinner.Value = 1;
            app.MaxSSpinnerLabel = uilabel(app.StimulusPanel);
            app.MaxSSpinnerLabel.Text = {'Max. (S)'; ''};
            app.MaxSSpinner = uispinner(app.StimulusPanel);
            app.MaxSSpinner.Limits = [0 Inf];
            app.MaxSSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSSpinnerValueChanged, true);
            app.MaxSSpinner.Value = 2;
            app.MaxVSpinnerLabel = uilabel(app.StimulusPanel);
            app.MaxVSpinnerLabel.Text = 'Max. (V)';
            app.MaxVSpinner = uispinner(app.StimulusPanel);
            app.MaxVSpinner.Limits = [0 Inf];
            app.MaxVSpinner.Value = 3;
            app.ofstepsSpinnerLabel = uilabel(app.StimulusPanel);
            app.ofstepsSpinnerLabel.Text = '# of steps';
            app.ofstepsSpinner = uispinner(app.StimulusPanel);
            app.ofstepsSpinner.Limits = [1 Inf];
            app.ofstepsSpinner.ValueChangedFcn = createCallbackFcn(app, @ofstepsSpinnerValueChanged, true);
            app.ofstepsSpinner.Value = 9;
            app.MinHzSpinnerLabel = uilabel(app.StimulusPanel);
            app.MinHzSpinnerLabel.Text = 'Min. (Hz)';
            app.MinHzSpinner = uispinner(app.StimulusPanel);
            app.MinHzSpinner.Limits = [0 Inf];
            app.MinHzSpinner.Value = 100;
            app.MaxHzSpinnerLabel = uilabel(app.StimulusPanel);
            app.MaxHzSpinnerLabel.Text = 'Max. (Hz)';
            app.MaxHzSpinner = uispinner(app.StimulusPanel);
            app.MaxHzSpinner.Limits = [1 Inf];
            app.MaxHzSpinner.Value = 1100;
            app.ofstepsSpinner_2Label = uilabel(app.StimulusPanel);
            app.ofstepsSpinner_2Label.Text = '# of steps';
            app.ofstepsSpinner_2 = uispinner(app.StimulusPanel);
            app.ofstepsSpinner_2.Limits = [0 Inf];
            app.ofstepsSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ofstepsSpinner_2ValueChanged, true);
            app.ofstepsSpinner_2.Value = 6;
            app.PreLoadStimuliButton = uibutton(app.StimulusPanel, 'push');
            app.PreLoadStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @PreLoadStimuliButtonPushed, true);
            app.PreLoadStimuliButton.Text = {'Pre-Load '; 'Stimuli'};
            app.VoltageSpinnerLabel = uilabel(app.StimulusPanel);
            app.VoltageSpinnerLabel.Text = 'Voltage';
            app.VoltageSpinner = uispinner(app.StimulusPanel);
            app.VoltageSpinner.Step = 0.1;
            app.VoltageSpinner.Limits = [0.1 3];
            app.VoltageSpinner.ValueChangedFcn = createCallbackFcn(app, @VoltageSpinnerValueChanged, true);
            app.VoltageSpinner.Value = 1.4;
            app.HzSpinnerLabel = uilabel(app.StimulusPanel);
            app.HzSpinnerLabel.Text = 'Hz';
            app.HzSpinner = uispinner(app.StimulusPanel);
            app.HzSpinner.ValueChangedFcn = createCallbackFcn(app, @HzSpinnerValueChanged, true);
            app.ExpeimentEstimatesPanel = uipanel(app.StimulusPanel);
            app.ExpeimentEstimatesPanel.Title = 'Expeiment Estimates';
            app.DurationLabel = uilabel(app.ExpeimentEstimatesPanel);
            app.DurationLabel.Text = 'Duration:';
            app.FramesLabel = uilabel(app.ExpeimentEstimatesPanel);
            app.FramesLabel.Text = 'Frames:';
            app.FrameRateLabel = uilabel(app.ExpeimentEstimatesPanel);
            app.FrameRateLabel.Text = 'Frame Rate:';
            app.FrameRateEditField = uieditfield(app.ExpeimentEstimatesPanel, 'numeric');
            app.FrameRateEditField.Limits = [0 200];
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.FrameRateEditField.Value = 30.05;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ExperimentPanel = uipanel(app.UIFigure);
            app.ExperimentPanel.Title = 'Experiment';
            app.TrialsEditFieldLabel = uilabel(app.ExperimentPanel);
            app.TrialsEditFieldLabel.Text = '# Trials';
            app.TrialsEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.TrialsEditField.Limits = [1 Inf];
            app.TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialsEditFieldValueChanged, true);
            app.TrialsEditField.Value = 10;
            app.ITIsLabel = uilabel(app.ExperimentPanel);
            app.ITIsLabel.Text = 'ITI (s)';
            app.ITIsEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.ITIsEditField.Limits = [0 Inf];
            app.ITIsEditField.ValueChangedFcn = createCallbackFcn(app, @ITIsEditFieldValueChanged, true);
            app.ITIsEditField.Value = 5;
            app.MouseIDEditFieldLabel = uilabel(app.ExperimentPanel);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.ExperimentPanel, 'text');
            app.MouseIDEditField.ValueChangedFcn = createCallbackFcn(app, @MouseIDEditFieldValueChanged, true);
            app.MouseIDEditField.Value = '0';
            app.ITIsEditField_2 = uieditfield(app.ExperimentPanel, 'numeric');
            app.ITIsEditField_2.Limits = [0 Inf];
            app.ITIsEditField_2.ValueChangedFcn = createCallbackFcn(app, @ITIsEditField_2ValueChanged, true);
            app.ITIsEditField_2.Value = 7;
            app.ImagingDataDirectoryEditFieldLabel = uilabel(app.ExperimentPanel);
            app.ImagingDataDirectoryEditFieldLabel.Text = 'Imaging Data Directory';
            app.ImagingDataDirectoryEditField = uieditfield(app.ExperimentPanel, 'text');
            app.ImagingDataDirectoryEditField.Value = '\\ramirez2022-1\Users\scanimage\Desktop\2Photon_Data';
            app.AudioonlyTrialsSpinnerLabel = uilabel(app.ExperimentPanel);
            app.AudioonlyTrialsSpinnerLabel.Text = {'Audio-only '; 'Trials %'};
            app.AudioonlyTrialsSpinner = uispinner(app.ExperimentPanel);
            app.AudioonlyTrialsSpinner.Step = 0.05;
            app.AudioonlyTrialsSpinner.Limits = [0 1];
            app.AudioonlyTrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @AudioonlyTrialsSpinnerValueChanged, true);
            app.AudioonlyTrialsSpinner.Value = 0.2;
            app.ImgDepthEditFieldLabel = uilabel(app.ExperimentPanel);
            app.ImgDepthEditFieldLabel.Text = 'Img. Depth';
            app.ImgDepthEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.ImgDepthEditField.ValueChangedFcn = createCallbackFcn(app, @ImgDepthEditFieldValueChanged, true);
            app.PButton = uibutton(app.ExperimentPanel, 'state');
            app.PButton.ValueChangedFcn = createCallbackFcn(app, @PButtonValueChanged, true);
            app.PButton.Text = '2P';
            app.BarGrabLabel = uilabel(app.UIFigure);
            app.BarGrabLabel.Text = {'Bar '; 'Grab'};
            app.BarGrabLamp = uilamp(app.UIFigure);
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.RewardPanel = uipanel(app.UIFigure);
            app.RewardPanel.Title = 'Reward';
            app.LiqSolButton = uibutton(app.RewardPanel, 'state');
            app.LiqSolButton.ValueChangedFcn = createCallbackFcn(app, @LiqSolButtonValueChanged, true);
            app.LiqSolButton.Text = 'Liq. Sol.';
            app.VacumSolenoidButton = uibutton(app.RewardPanel, 'state');
            app.VacumSolenoidButton.ValueChangedFcn = createCallbackFcn(app, @VacumSolenoidButtonValueChanged, true);
            app.VacumSolenoidButton.Text = 'Vac. Sol.';
            app.RewardSizemsLabel = uilabel(app.RewardPanel);
            app.RewardSizemsLabel.Text = {'Reward '; 'Size (ms)'};
            app.RewardSizemsSpinner = uispinner(app.RewardPanel);
            app.RewardSizemsSpinner.Limits = [0 25];
            app.RewardSizemsSpinner.ValueChangedFcn = createCallbackFcn(app, @RewardSizemsSpinnerValueChanged, true);
            app.RewardSizemsSpinner.Value = 8;
            app.GiveRewardButton = uibutton(app.RewardPanel, 'push');
            app.GiveRewardButton.ButtonPushedFcn = createCallbackFcn(app, @GiveRewardButtonPushed, true);
            app.GiveRewardButton.Text = {'Give'; 'Reward'};
            app.sLickTimeoutSpinnerLabel = uilabel(app.RewardPanel);
            app.sLickTimeoutSpinnerLabel.Text = {'(s) Lick'; 'Timeout'};
            app.sLickTimeoutSpinner = uispinner(app.RewardPanel);
            app.sLickTimeoutSpinner.Step = 0.1;
            app.sLickTimeoutSpinner.Limits = [0 10];
            app.sLickTimeoutSpinner.ValueChangedFcn = createCallbackFcn(app, @sLickTimeoutSpinnerValueChanged, true);
            app.sLickTimeoutSpinner.Value = 1.5;
            app.PairedRewardButton = uibutton(app.RewardPanel, 'state');
            app.PairedRewardButton.ValueChangedFcn = createCallbackFcn(app, @PairedRewardButtonValueChanged, true);
            app.PairedRewardButton.Text = 'Paired Reward';
            app.RandomRewardButton = uibutton(app.RewardPanel, 'state');
            app.RandomRewardButton.ValueChangedFcn = createCallbackFcn(app, @RandomRewardButtonValueChanged, true);
            app.RandomRewardButton.Text = 'Random Reward';
            app.rewardPairValSwitch = uiswitch(app.RewardPanel, 'slider');
            app.rewardPairValSwitch.Items = {'Low', 'High'};
            app.rewardPairValSwitch.ValueChangedFcn = createCallbackFcn(app, @rewardPairValSwitchValueChanged, true);
            app.rewardPairValSwitch.Value = 'Low';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'2P', 'Ephy'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '2P';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stimulator_Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end